classdef Project_1_11_20_2019 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ForwardKinematicsButton     matlab.ui.control.Button
        Theta0EditFieldLabel        matlab.ui.control.Label
        Theta0EditField             matlab.ui.control.EditField
        Theta1EditFieldLabel        matlab.ui.control.Label
        Theta1EditField             matlab.ui.control.EditField
        Theta2EditFieldLabel        matlab.ui.control.Label
        Theta2EditField             matlab.ui.control.EditField
        Theta3EditFieldLabel        matlab.ui.control.Label
        Theta3EditField             matlab.ui.control.EditField
        Theta4EditFieldLabel        matlab.ui.control.Label
        Theta4EditField             matlab.ui.control.EditField
        XEditFieldLabel             matlab.ui.control.Label
        XEditField                  matlab.ui.control.EditField
        YEditFieldLabel             matlab.ui.control.Label
        YEditField                  matlab.ui.control.EditField
        ZEditFieldLabel             matlab.ui.control.Label
        ZEditField                  matlab.ui.control.EditField
        PhiEditFieldLabel           matlab.ui.control.Label
        PhiEditField                matlab.ui.control.EditField
        ThetaEditFieldLabel         matlab.ui.control.Label
        ThetaEditField              matlab.ui.control.EditField
        PsiEditFieldLabel           matlab.ui.control.Label
        PsiEditField                matlab.ui.control.EditField
        UITableForward              matlab.ui.control.Table
        PrintToTableForwardButton   matlab.ui.control.Button
        InverseKinematicsButton     matlab.ui.control.Button
        PrintToTableInverseButton   matlab.ui.control.Button
        UITableInverse              matlab.ui.control.Table
        PUMA560ForwardandInverseKinematicsCalculatorLabel  matlab.ui.control.Label
        ForwardKinematicsDataLabel  matlab.ui.control.Label
        InverseKinematicsDataLabel  matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
        PlotButton                  matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: ForwardKinematicsButton
        function ForwardKinematicsButtonPushed(app, event)
%Code for calculating the forward kinematics

%Link Length
a1=0;
a2=432;
a3=432;
a4=0;
a5=0;

%Link Offset
d1=0;
d2=-149;
d3=0;
d4=0;
d5=0;

%Link Twist
al_1=-90;
al_2=0;
al_3=-90;
al_4=90;
al_5=0;

%Joint variables in degrees
c1=cosd(str2double(app.Theta0EditField.Value));
s1=sind(str2double(app.Theta0EditField.Value));
c2=cosd(str2double(app.Theta1EditField.Value));
s2=sind(str2double(app.Theta1EditField.Value));
c3=cosd(str2double(app.Theta2EditField.Value));
s3=sind(str2double(app.Theta2EditField.Value));
c4=cosd(str2double(app.Theta3EditField.Value));
s4=sind(str2double(app.Theta3EditField.Value));
c5=cosd(str2double(app.Theta4EditField.Value));
s5=sind(str2double(app.Theta4EditField.Value));

%Transformation Matrix 
T_1_to_0 = [c1 -s1*cosd(al_1) s1*sind(al_1) a1*c1; s1 c1*cosd(al_1) -c1*sind(al_1) a1*s1; 0 sind(al_1) cosd(al_1) d1; 0 0 0 1];
T_2_to_1 = [c2 -s2*cosd(al_2) s2*sind(al_2) a2*c2; s2 c2*cosd(al_2) -c2*sind(al_2) a2*s2; 0 sind(al_2) cosd(al_2) d2; 0 0 0 1];
T_3_to_2 = [c3 -s3*cosd(al_3) s3*sind(al_3) a3*c3; s3 c3*cosd(al_3) -c3*sind(al_3) a3*s3; 0 sind(al_3) cosd(al_3) d3; 0 0 0 1];
T_4_to_3 = [c4 -s4*cosd(al_4) s4*sind(al_4) a4*c4; s4 c4*cosd(al_4) -c4*sind(al_4) a4*s4; 0 sind(al_4) cosd(al_4) d4; 0 0 0 1];
T_5_to_4 = [c5 -s5*cosd(al_5) s5*sind(al_5) a5*c5; s5 c5*cosd(al_5) -c5*sind(al_5) a5*s5; 0 sind(al_5) cosd(al_5) d5; 0 0 0 1];
T_5_to_0 = T_1_to_0 * T_2_to_1 * T_3_to_2 * T_4_to_3 * T_5_to_4;

%Position and Orientation
app.XEditField.Value=num2str(T_5_to_0(1,4));
app.YEditField.Value=num2str(T_5_to_0(2,4));
app.ZEditField.Value=num2str(T_5_to_0(3,4));

r33=T_5_to_0(3,3);
r31=T_5_to_0(3,1);
r32=T_5_to_0(3,2);

%Euler angles based on ZYZ transformation
theta_pos=atan2d(sqrt(1-r33.^2),r33);
psi_pos=atan2d(r32, -r31);
theta_neg=atan2d(-sqrt(1-r33.^2),r33);
psi_neg=atan2d(-r32, r31);

if ((abs(theta_pos) < 100) && (abs(psi_pos) < 266))
    app.ThetaEditField.Value = num2str(theta_pos);
    app.PsiEditField.Value = num2str(psi_pos);
elseif ((abs(theta_neg) < 100) && (abs(psi_neg) < 266))
    app.ThetaEditField.Value = num2str(theta_neg);
    app.PsiEditField.Value = num2str(psi_neg);
else
   app.ThetaEditField.Value = "No Good";
   app.PsiEditField.Value = "No Good";
end

app.PhiEditField.Value=num2str(0);
        end

        % Button pushed function: PrintToTableForwardButton
        function PrintToTableForwardButtonPushed(app, event)
            %Table for Forward Kinematics    
            if isempty(app.UITableForward.Data)
                    app.UITableForward.Data = {app.XEditField.Value, app.YEditField.Value, app.ZEditField.Value, app.PhiEditField.Value, app.ThetaEditField.Value, app.PsiEditField.Value};
            else
                    Data = get(app.UITableForward,'Data');
                    Data(end+1,:) = {app.XEditField.Value, app.YEditField.Value, app.ZEditField.Value, app.PhiEditField.Value, app.ThetaEditField.Value, app.PsiEditField.Value};
                    set(app.UITableForward,'Data',Data)
            end
        end

        % Button pushed function: InverseKinematicsButton
        function InverseKinematicsButtonPushed(app, event)
%Code for calculating the inverse kinematics

%Link Lenth
a1=0;
a2=432;
a3=432;
a4=0;
a5=0;

%Link Offset
d1=0;
d2=-149;
d3=0;
d4=0;
d5=0;

%Positions and orientations
x = str2double(app.XEditField.Value);
y = str2double(app.YEditField.Value);
z = str2double(app.ZEditField.Value);
phi = str2double(app.PhiEditField.Value);
theta = str2double(app.ThetaEditField.Value);
psi = str2double(app.PsiEditField.Value);

r13=cosd(phi) * sind(theta);
r23=sind(phi) * sind(theta);
r33=cos(theta);


%Theta0_pos, Theta2_pos, Theta3_0p2p, Theta4_0p4p
theta0_pos = atan2d(y,x) - atan2d(-149, sqrt(x.^2 + y.^2 - 149^2));
k = (x*cosd(theta0_pos) + (y*sind(theta0_pos)))^2 + z.^2 - a2^2 - a3^2 - 432^2;
n = 4*(a2^2)*(a3^2) + 4*(a2^2)*(432^2);
theta2_pos = atan2d(k,sqrt(n - k.^2)) - atan2d(a3,432);
f1 = a2*cosd(theta2_pos) + a3;
f2 = 432 + a2*sind(theta2_pos);
theta23 = atan2d((f2*(x*cosd(theta0_pos) + (y*sind(theta0_pos))) - f1*z),f1*(x*cosd(theta0_pos) + (y*sind(theta0_pos)))+f2*z);
theta1_0p2p = theta23 - theta2_pos;
theta3_0p2p = atan2d((-cosd(theta0_pos)*sind(theta1_0p2p + theta2_pos)*r13 - ...
    sind(theta0_pos)*sind(theta1_0p2p + theta2_pos)*r23 + cosd(theta1_0p2p + theta2_pos)*r33), ...
    (cosd(theta0_pos)*cosd(theta1_0p2p + theta2_pos)*r13 + sind(theta0_pos)*cosd(theta1_0p2p + theta2_pos) ...
    *r23 + sind(theta1_0p2p + theta2_pos)*r33));
theta4_0p4p = atan2d(sqrt(1 - (sind(theta0_pos)*r13 - cosd(theta0_pos)*r23).^2), ...
    sind(theta0_pos)*r13 - cosd(theta0_pos)*r23);


%Theta0_neg, Theta2_pos, Theta3_0n2p, Theta4_0n4p
theta0_neg = atan2d(y,x) - atan2d(-149, -1*sqrt(x.^2 + y.^2 - 149^2));
k = (x*cosd(theta0_neg) + (y*sind(theta0_neg)))^2 + z.^2 - a2^2 - a3^2 - 432^2;
n = 4*(a2^2)*(a3^2) + 4*(a2^2)*(432^2);
theta2_pos = atan2d(k,sqrt(n - k.^2)) - atan2d(a3,432);
f1 = a2*cosd(theta2_pos) + a3;
f2 = 432 + a2*sind(theta2_pos);
theta23 = atan2d((f2*(x*cosd(theta0_neg) + (y*sind(theta0_neg))) - f1*z),f1*(x*cosd(theta0_neg) + (y*sind(theta0_neg)))+f2*z);
theta1_0n2p = theta23 - theta2_pos;
theta3_0n2p = atan2d((-cosd(theta0_neg)*sind(theta1_0n2p + theta2_pos)*r13 - ...
    sind(theta0_neg)*sind(theta1_0n2p + theta2_pos)*r23 + cosd(theta1_0n2p + theta2_pos)*r33), ...
    (cosd(theta0_neg)*cosd(theta1_0n2p + theta2_pos)*r13 + sind(theta0_neg)*cosd(theta1_0n2p + theta2_pos) ...
    *r23 + sind(theta1_0n2p + theta2_pos)*r33));
theta4_0n4p = atan2d(sqrt(1 - (sind(theta0_neg)*r13 - cosd(theta0_neg)*r23).^2), ...
    sind(theta0_neg)*r13 - cosd(theta0_neg)*r23);


%Theta0_pos, Theta2_neg, Theta3_0p2n, Theta4_0p4p
theta0_pos = atan2d(y,x) - atan2d(-149, sqrt(x.^2 + y.^2 - 149^2));
k = (x*cosd(theta0_pos) + (y*sind(theta0_pos)))^2 + z.^2 - a2^2 - a3^2 - 432^2;
n = 4*(a2^2)*(a3^2) + 4*(a2^2)*(432^2);
theta2_neg = atan2d(k,-1*sqrt(n - k.^2)) - atan2d(a3,432);
f1 = a2*cosd(theta2_neg) + a3;
f2 = 432 + a2*sind(theta2_neg);
theta23 = atan2d((f2*(x*cosd(theta0_pos) + (y*sind(theta0_pos))) - f1*z),f1*(x*cosd(theta0_pos) + (y*sind(theta0_pos)))+f2*z);
theta1_0p2n = theta23 - theta2_neg;
theta3_0p2n = atan2d((-cosd(theta0_pos)*sind(theta1_0p2n + theta2_neg)*r13 - ...
    sind(theta0_pos)*sind(theta1_0p2n + theta2_neg)*r23 + cosd(theta1_0p2n + theta2_neg)*r33), ...
    (cosd(theta0_pos)*cosd(theta1_0p2n + theta2_neg)*r13 + sind(theta0_pos)*cosd(theta1_0p2n + theta2_neg) ...
    *r23 + sind(theta1_0p2n + theta2_neg)*r33));
theta4_0p4p = atan2d(sqrt(1 - (sind(theta0_pos)*r13 - cosd(theta0_pos)*r23).^2), ...
    sind(theta0_pos)*r13 - cosd(theta0_pos)*r23);


%Theta0_neg, Theta2_neg, Theta3_0n2n, Theta4_0n4p
theta0_neg = atan2d(y,x) - atan2d(-149, -1*sqrt(x.^2 + y.^2 - 149^2));
k = (x*cosd(theta0_neg) + (y*sind(theta0_neg)))^2 + z.^2 - a2^2 - a3^2 - 432^2;
n = 4*(a2^2)*(a3^2) + 4*(a2^2)*(432^2);
theta2_neg = atan2d(k,-1*sqrt(n - k.^2)) - atan2d(a3,432);
f1 = a2*cosd(theta2_neg) + a3;
f2 = 432 + a2*sind(theta2_neg);
theta23 = atan2d((f2*(x*cosd(theta0_neg) + (y*sind(theta0_neg))) - f1*z),f1*(x*cosd(theta0_neg) + (y*sind(theta0_neg)))+f2*z);
theta1_0n2n = theta23 - theta2_neg;
theta3_0n2n = atan2d((-cosd(theta0_neg)*sind(theta1_0n2n + theta2_neg)*r13 - ...
    sind(theta0_neg)*sind(theta1_0n2n + theta2_neg)*r23 + cosd(theta1_0n2n + theta2_neg)*r33), ...
    (cosd(theta0_neg)*cosd(theta1_0n2n + theta2_neg)*r13 + sind(theta0_neg)*cosd(theta1_0n2n + theta2_neg) ...
    *r23 + sind(theta1_0n2n + theta2_neg)*r33));
theta4_0n4p = atan2d(sqrt(1 - (sind(theta0_neg)*r13 - cosd(theta0_neg)*r23).^2), ...
    sind(theta0_neg)*r13 - cosd(theta0_neg)*r23);


%Theta0_pos, Theta2_pos, Theta3_0p2p, Theta4_0p4n
theta0_pos = atan2d(y,x) - atan2d(-149,sqrt(x.^2 + y.^2 - 149^2));
k = (x*cosd(theta0_pos) + (y*sind(theta0_pos)))^2 + z.^2 - a2^2 - a3^2 - 432^2;
n = 4*(a2^2)*(a3^2) + 4*(a2^2)*(432^2);
theta2_pos = atan2d(k,sqrt(n - k.^2)) - atan2d(a3,432);
f1 = a2*cosd(theta2_pos) + a3;
f2 = 432 + a2*sind(theta2_pos);
theta23 = atan2d((f2*(x*cosd(theta0_pos) + (y*sind(theta0_pos))) - f1*z),f1*(x*cosd(theta0_pos) + (y*sind(theta0_pos)))+f2*z);
theta1_0p2p = theta23 - theta2_pos;
theta4_0p4n = atan2d(-1*sqrt(1 - (sind(theta0_pos)*r13 - cosd(theta0_pos)*r23).^2), ...
    sind(theta0_pos)*r13 - cosd(theta0_pos)*r23);


%Theta0_neg, Theta2_pos, Theta3_0n2p, Theta4_0n4n
theta0_neg = atan2d(y,x) - atan2d(-149,-1*sqrt(x.^2 + y.^2 - 149^2));
k = (x*cosd(theta0_neg) + (y*sind(theta0_neg)))^2 + z.^2 - a2^2 - a3^2 - 432^2;
n = 4*(a2^2)*(a3^2) + 4*(a2^2)*(432^2);
theta2_pos = atan2d(k,sqrt(n - k.^2)) - atan2d(a3,432);
f1 = a2*cosd(theta2_pos) + a3;
f2 = 432 + a2*sind(theta2_pos);
theta23 = atan2d((f2*(x*cosd(theta0_neg) + (y*sind(theta0_neg))) - f1*z),f1*(x*cosd(theta0_neg) + (y*sind(theta0_neg)))+f2*z);
theta1_0n2p = theta23 - theta2_pos;
theta4_0n4n = atan2d(-1*sqrt(1 - (sind(theta0_neg)*r13 - cosd(theta0_neg)*r23).^2), ...
    sind(theta0_neg)*r13 - cosd(theta0_neg)*r23);

            
            %Theta0 Out of Range Detection
            if (abs(theta0_pos) && abs(theta0_neg)) <= 160
                app.Theta0EditField.Value = num2str(theta0_pos);
            elseif abs(theta0_pos) > 160 && abs(theta0_neg) <= 160
                app.Theta0EditField.Value = num2str(theta0_neg);
            elseif abs(theta0_pos) <= 160 && abs(theta0_neg) > 160
                app.Theta0EditField.Value = num2str(theta0_pos);
            else
                app.Theta0EditField.Value = "Out of Range";
            end
            
            if (x^2 + y^2 - d3^2) < 0
                app.Theta0EditField.Value = "SINGULARITY";
            end
            
            %Theta2 Out of Range Detection
            if (abs(theta2_pos) && abs(theta2_neg)) <= 135
                app.Theta2EditField.Value = num2str(theta2_pos);
            elseif abs(theta2_pos) > 135 && abs(theta2_neg) <= 135
                app.Theta2EditField.Value = num2str(theta2_neg);
            elseif abs(theta2_pos) <= 135 && abs(theta2_neg) > 135
                app.Theta2EditField.Value = num2str(theta2_pos);
            else
                app.Theta2EditField.Value = "Out of Range";
            end
            
            if (n - k^2) < 0
                app.Theta2EditField.Value = "SINGULARITY";
            end
            
            %Theta1 Out of Range Detection
            if (abs(theta1_0p2p) && abs(theta1_0n2p) && abs(theta1_0p2n) && abs(theta1_0n2n)) <= 125
                app.Theta1EditField.Value = num2str(theta1_0p2p);
            elseif abs(theta1_0p2p) <= 125
                app.Theta1EditField.Value = num2str(theta1_0p2p);
            elseif abs(theta1_0n2p) <= 125
                app.Theta1EditField.Value = num2str(theta1_0n2p);
            elseif abs(theta1_0p2n) <= 125
                app.Theta1EditField.Value = num2str(theta1_0p2n);
            elseif abs(theta1_0n2n) <= 125
                app.Theta1EditField.Value = num2str(theta1_0n2n);
            else
                app.Theta1EditField.Value = "Out of Range";
            end
            
            %Theta3 Out of Range Detection
            if abs(theta3_0p2p) <= 100
                app.Theta3EditField.Value = num2str(theta3_0p2p);
            elseif abs(theta3_0p2n) <= 100
                app.Theta3EditField.Value = num2str(theta3_0p2n);
            elseif abs(theta3_0n2p) <= 100
                app.Theta3EditField.Value = num2str(theta3_0n2p);
            elseif abs(theta3_0n2n) <= 100
                app.Theta3EditField.Value = num2str(theta3_0n2n);
            else
                app.Theta3EditField.Value = "Out of Range";
            end
            
            %Theta4 Out of Range Detection
            if abs(theta4_0n4p) <= 266
                 app.Theta4EditField.Value = num2str(theta4_0n4p);
            elseif abs(theta4_0p4p) <= 266
                 app.Theta4EditField.Value = num2str(theta4_0p4p);
            elseif abs(theta4_0p4n) <= 266
                 app.Theta4EditField.Value = num2str(theta4_0p4n);
            elseif abs(theta4_0n4n) <= 266
                 app.Theta4EditField.Value = num2str(theta4_0n4n);
            else 
                app.Theta4EditField.Value = "Out of Range";
            end
        end

        % Button pushed function: PrintToTableInverseButton
        function PrintToTableInverseButtonPushed(app, event)
            %Table for Forward Kinematics
            
            if isempty(app.UITableInverse.Data)
                    app.UITableInverse.Data = {app.Theta0EditField.Value, app.Theta1EditField.Value, app.Theta2EditField.Value, app.Theta3EditField.Value, app.Theta4EditField.Value};
            else
                    Data = get(app.UITableInverse,'Data');
                    Data(end+1,:) = {app.Theta0EditField.Value, app.Theta1EditField.Value, app.Theta2EditField.Value, app.Theta3EditField.Value, app.Theta4EditField.Value};
                    set(app.UITableInverse,'Data',Data)
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            %Position Change
            x = linspace(100,500,9);   

            %Converting the table data from string to double
            Data = str2double(app.UITableInverse.Data);
               
            %Assigning all rows of each column to specific variables
            th0 = Data(:,1);
            th1 = Data(:,2);
            th2 = Data(:,3);
            th3 = Data(:,4);
            th4 = Data(:,5);

            %Plotting all of the joint variables
            plot(app.UIAxes, x, th0);
            drawnow;
            hold(app.UIAxes)
            plot(app.UIAxes, x, th1);
            plot(app.UIAxes, x, th2);
            plot(app.UIAxes, x, th3);
            plot(app.UIAxes, x, th4);
            
            %Plot legend
            legend(app.UIAxes, 'Th0','Th1','Th2','Th3','Th4') 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ForwardKinematicsButton = uibutton(app.UIFigure, 'push');
            app.ForwardKinematicsButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardKinematicsButtonPushed, true);
            app.ForwardKinematicsButton.Text = 'Forward Kinematics';
            app.Theta0EditFieldLabel = uilabel(app.UIFigure);
            app.Theta0EditFieldLabel.Text = 'Theta0';
            app.Theta0EditField = uieditfield(app.UIFigure, 'text');
            app.Theta1EditFieldLabel = uilabel(app.UIFigure);
            app.Theta1EditFieldLabel.Text = 'Theta1';
            app.Theta1EditField = uieditfield(app.UIFigure, 'text');
            app.Theta2EditFieldLabel = uilabel(app.UIFigure);
            app.Theta2EditFieldLabel.Text = 'Theta2';
            app.Theta2EditField = uieditfield(app.UIFigure, 'text');
            app.Theta3EditFieldLabel = uilabel(app.UIFigure);
            app.Theta3EditFieldLabel.Text = 'Theta3';
            app.Theta3EditField = uieditfield(app.UIFigure, 'text');
            app.Theta4EditFieldLabel = uilabel(app.UIFigure);
            app.Theta4EditFieldLabel.Text = 'Theta4';
            app.Theta4EditField = uieditfield(app.UIFigure, 'text');
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'text');
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'text');
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'text');
            app.PhiEditFieldLabel = uilabel(app.UIFigure);
            app.PhiEditFieldLabel.Text = 'Phi';
            app.PhiEditField = uieditfield(app.UIFigure, 'text');
            app.ThetaEditFieldLabel = uilabel(app.UIFigure);
            app.ThetaEditFieldLabel.Text = 'Theta';
            app.ThetaEditField = uieditfield(app.UIFigure, 'text');
            app.PsiEditFieldLabel = uilabel(app.UIFigure);
            app.PsiEditFieldLabel.Text = 'Psi';
            app.PsiEditField = uieditfield(app.UIFigure, 'text');
            app.UITableForward = uitable(app.UIFigure);
            app.UITableForward.ColumnName = {'X'; 'Y'; 'Z'; 'Phi'; 'Theta'; 'Psi'};
            app.UITableForward.RowName = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'; ''};
            app.PrintToTableForwardButton = uibutton(app.UIFigure, 'push');
            app.PrintToTableForwardButton.ButtonPushedFcn = createCallbackFcn(app, @PrintToTableForwardButtonPushed, true);
            app.PrintToTableForwardButton.Text = 'Print To Table Forward';
            app.InverseKinematicsButton = uibutton(app.UIFigure, 'push');
            app.InverseKinematicsButton.ButtonPushedFcn = createCallbackFcn(app, @InverseKinematicsButtonPushed, true);
            app.InverseKinematicsButton.Text = 'Inverse Kinematics';
            app.PrintToTableInverseButton = uibutton(app.UIFigure, 'push');
            app.PrintToTableInverseButton.ButtonPushedFcn = createCallbackFcn(app, @PrintToTableInverseButtonPushed, true);
            app.PrintToTableInverseButton.Text = 'Print To Table Inverse';
            app.UITableInverse = uitable(app.UIFigure);
            app.UITableInverse.ColumnName = {'Theta0'; 'Theta1'; 'Theta2'; 'Theta3'; 'Theta4'};
            app.UITableInverse.RowName = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'};
            app.PUMA560ForwardandInverseKinematicsCalculatorLabel = uilabel(app.UIFigure);
            app.PUMA560ForwardandInverseKinematicsCalculatorLabel.Text = 'PUMA 560 Forward and Inverse Kinematics Calculator';
            app.ForwardKinematicsDataLabel = uilabel(app.UIFigure);
            app.ForwardKinematicsDataLabel.Text = 'Forward Kinematics Data';
            app.InverseKinematicsDataLabel = uilabel(app.UIFigure);
            app.InverseKinematicsDataLabel.Text = 'Inverse Kinematics Data';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Joint Variables vs. Position')
            xlabel(app.UIAxes, 'Z-Position (mm)')
            ylabel(app.UIAxes, 'Theta 0,1,2,3,4 (deg)')
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Project_1_11_20_2019

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end